classdef interactive < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Xuan_JRLabel                  matlab.ui.control.Label
        ThanksforHengRunhuiPingLabel  matlab.ui.control.Label
        s2frameEditField              matlab.ui.control.NumericEditField
        frame1sEditFieldLabel         matlab.ui.control.Label
        unit2umEditField              matlab.ui.control.NumericEditField
        unitumEditFieldLabel          matlab.ui.control.Label
        pixel2umEditField             matlab.ui.control.NumericEditField
        pixelumEditFieldLabel         matlab.ui.control.Label
        folder_of_saved_figuresEditField  matlab.ui.control.EditField
        folderofsavedfiguresEditFieldLabel  matlab.ui.control.Label
        full_path_to_saved_csv_fileEditField  matlab.ui.control.EditField
        fullpathtosavedcsvfileEditFieldLabel  matlab.ui.control.Label
        load_mcd_and_use_machine_labelButton  matlab.ui.control.Button
        full_path_to_mcdEditField     matlab.ui.control.EditField
        fullpathtomcdEditFieldLabel   matlab.ui.control.Label
        play_videoButton              matlab.ui.control.Button
        full_path_to_the_playerEditField  matlab.ui.control.EditField
        fullpathtotheplayerEditFieldLabel  matlab.ui.control.Label
        full_path_to_the_videoEditField  matlab.ui.control.EditField
        fullpathtothevideoEditFieldLabel  matlab.ui.control.Label
    end

    
    methods (Access = private)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             videoPath = app.full_path_to_the_videoEditField.Value;
%             playerPath = app.full_path_to_the_playerEditField.Value;
            global pixel2um unit2um s2frame label_number_outlier
            label_number_outlier = 100;
            pixel2um = app.pixel2umEditField.Value;
            unit2um = app.unit2umEditField.Value;
            s2frame = app.s2frameEditField.Value;
        end

        % Value changed function: full_path_to_the_videoEditField
        function full_path_to_the_videoEditFieldValueChanged(app, event)
%             videoPath = app.full_path_to_the_videoEditField.Value;            
        end

        % Value changed function: full_path_to_the_playerEditField
        function full_path_to_the_playerEditFieldValueChanged(app, event)
%             playerPath = app.full_path_to_the_playerEditField.Value;            
        end

        % Button pushed function: play_videoButton
        function play_videoButtonPushed(app, event)
            command = sprintf('"%s" "%s"', app.full_path_to_the_playerEditField.Value, app.full_path_to_the_videoEditField.Value);
            system(command);
        end

        % Button pushed function: 
        % load_mcd_and_use_machine_labelButton
        function load_mcd_and_use_machine_labelButtonPushed(app, event)
            
            clc;
            close all;
            disp('start, please wait...')
            full_path_to_mcd = app.full_path_to_mcdEditField.Value;
            full_path_to_saved_csv = app.full_path_to_saved_csv_fileEditField.Value;
            folder_of_saved_figures = app.folder_of_saved_figuresEditField.Value;
            
            mcd = input_data(full_path_to_mcd);
            label_rearranged = machine_label(mcd);
            output_label(label_rearranged, full_path_to_saved_csv);
            output_figures(folder_of_saved_figures)

        end

        % Value changed function: full_path_to_mcdEditField
        function full_path_to_mcdEditFieldValueChanged(app, event)
%             value = app.full_path_to_mcdEditField.Value;
            
        end

        % Value changed function: 
        % full_path_to_saved_csv_fileEditField
        function full_path_to_saved_csv_fileEditFieldValueChanged(app, event)
%             value = app.full_path_to_saved_csv_fileEditField.Value;
            
        end

        % Value changed function: folder_of_saved_figuresEditField
        function folder_of_saved_figuresEditFieldValueChanged(app, event)
%             value = app.folder_of_saved_figuresEditField.Value;
            
        end

        % Value changed function: pixel2umEditField
        function pixel2umEditFieldValueChanged(app, event)
            pixel2um = app.pixel2umEditField.Value;
            
        end

        % Value changed function: unit2umEditField
        function unit2umEditFieldValueChanged(app, event)
            unit2um = app.unit2umEditField.Value;
            
        end

        % Value changed function: s2frameEditField
        function s2frameEditFieldValueChanged(app, event)
            s2frame = app.s2frameEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.fullpathtothevideoEditFieldLabel = uilabel(app.UIFigure);
            app.fullpathtothevideoEditFieldLabel.Text = 'full path to the video';
            app.full_path_to_the_videoEditField = uieditfield(app.UIFigure, 'text');
            app.full_path_to_the_videoEditField.ValueChangedFcn = createCallbackFcn(app, @full_path_to_the_videoEditFieldValueChanged, true);
            app.full_path_to_the_videoEditField.Value = 'F:\1_learning\research\Colbert\NC\NC20230312\w1\20230312_1921_w1_HUDS.avi';
            app.fullpathtotheplayerEditFieldLabel = uilabel(app.UIFigure);
            app.fullpathtotheplayerEditFieldLabel.Text = 'full path to the player';
            app.full_path_to_the_playerEditField = uieditfield(app.UIFigure, 'text');
            app.full_path_to_the_playerEditField.ValueChangedFcn = createCallbackFcn(app, @full_path_to_the_playerEditFieldValueChanged, true);
            app.full_path_to_the_playerEditField.Value = 'D:\Public\20220721_VirtualDub\VirtualDub-1.10.4-AMD64\Veedub64.exe';
            app.play_videoButton = uibutton(app.UIFigure, 'push');
            app.play_videoButton.ButtonPushedFcn = createCallbackFcn(app, @play_videoButtonPushed, true);
            app.play_videoButton.Text = 'play video';
            app.fullpathtomcdEditFieldLabel = uilabel(app.UIFigure);
            app.fullpathtomcdEditFieldLabel.Text = 'full path to mcd';
            app.full_path_to_mcdEditField = uieditfield(app.UIFigure, 'text');
            app.full_path_to_mcdEditField.ValueChangedFcn = createCallbackFcn(app, @full_path_to_mcdEditFieldValueChanged, true);
            app.full_path_to_mcdEditField.Value = 'C:\Users\11097\Nutstore\1\DATA_Wenlab\23.3.10 - N2 taxis Colbert - without rawdata\data\NC\NC20230312\w1\NC20230312w1_mcd_corred.mat';
            app.load_mcd_and_use_machine_labelButton = uibutton(app.UIFigure, 'push');
            app.load_mcd_and_use_machine_labelButton.ButtonPushedFcn = createCallbackFcn(app, @load_mcd_and_use_machine_labelButtonPushed, true);
            app.load_mcd_and_use_machine_labelButton.Text = 'load mcd and use machine label';
            app.fullpathtosavedcsvfileEditFieldLabel = uilabel(app.UIFigure);
            app.fullpathtosavedcsvfileEditFieldLabel.Text = 'full path to saved .csv file';
            app.full_path_to_saved_csv_fileEditField = uieditfield(app.UIFigure, 'text');
            app.full_path_to_saved_csv_fileEditField.ValueChangedFcn = createCallbackFcn(app, @full_path_to_saved_csv_fileEditFieldValueChanged, true);
            app.full_path_to_saved_csv_fileEditField.Value = 'C:\Users\11097\Desktop\machine_label.csv';
            app.folderofsavedfiguresEditFieldLabel = uilabel(app.UIFigure);
            app.folderofsavedfiguresEditFieldLabel.Text = 'folder of saved figures';
            app.folder_of_saved_figuresEditField = uieditfield(app.UIFigure, 'text');
            app.folder_of_saved_figuresEditField.ValueChangedFcn = createCallbackFcn(app, @folder_of_saved_figuresEditFieldValueChanged, true);
            app.folder_of_saved_figuresEditField.Value = 'C:\Users\11097\Desktop';
            app.pixelumEditFieldLabel = uilabel(app.UIFigure);
            app.pixelumEditFieldLabel.Text = '1 pixel == ? um';
            app.pixel2umEditField = uieditfield(app.UIFigure, 'numeric');
            app.pixel2umEditField.ValueChangedFcn = createCallbackFcn(app, @pixel2umEditFieldValueChanged, true);
            app.pixel2umEditField.Value = 1.6835;
            app.unitumEditFieldLabel = uilabel(app.UIFigure);
            app.unitumEditFieldLabel.Text = '1 unit == ? um';
            app.unit2umEditField = uieditfield(app.UIFigure, 'numeric');
            app.unit2umEditField.ValueChangedFcn = createCallbackFcn(app, @unit2umEditFieldValueChanged, true);
            app.unit2umEditField.Value = 0.05;
            app.frame1sEditFieldLabel = uilabel(app.UIFigure);
            app.frame1sEditFieldLabel.Text = '? frame == 1 s';
            app.s2frameEditField = uieditfield(app.UIFigure, 'numeric');
            app.s2frameEditField.ValueChangedFcn = createCallbackFcn(app, @s2frameEditFieldValueChanged, true);
            app.s2frameEditField.Value = 66;
            app.ThanksforHengRunhuiPingLabel = uilabel(app.UIFigure);
            app.ThanksforHengRunhuiPingLabel.Text = 'Thanks for Heng, Runhui, Ping';
            app.Xuan_JRLabel = uilabel(app.UIFigure);
            app.Xuan_JRLabel.Text = 'Xuan_JR';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interactive

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end